classdef Plot_Serial_App_nopopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        PlotSerialInDataLabel          matlab.ui.control.Label
        SerialCommunicationsDataPanel  matlab.ui.container.Panel
        Setto123toturnLEDonLabel       matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        editSerialIn                   matlab.ui.control.EditField
        text3                          matlab.ui.control.Label
        editSerialOut                  matlab.ui.control.EditField
        ControlsPanel                  matlab.ui.container.Panel
        ChooseUSBDropDown              matlab.ui.control.DropDown
        ChooseUSBDropDownLabel         matlab.ui.control.Label
        pushbuttonStop                 matlab.ui.control.Button
        writingtoafileLabel            matlab.ui.control.Label
        PressButtontobeginLabel        matlab.ui.control.Label
        pushbuttonFile                 matlab.ui.control.Button
        axesTemp                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialPort % Description
        hTempPlot % Description
        arduinoVals % Description
    end
    
    methods (Access = private)
                
        function instrcallback(app, ~, ~)
                %  INSTRCALLBACK - callback for serial.BytesAvailableFunction
                %  This gets called whenever new data is available on the serial port.
                %  This is a callback function, so the state of the handles is the same
                %  state as when this function was first called. Handles is passed as
                %  copy. This also means that handles cannot be updated from this
                %  method so guidata(hObject, handles) will not work here.
                global serialInBuffer;
                global timeBuffer;
                global dataFile;

                a = readline(app.serialPort);
                % Check that the json data is intact by checking that there is a { at
                % the beginning and a } at the end of the string.
%                 if length(regexp(a,'^{\w*|\W*}$')) == 2
                    try % Error handling in case the JSON message is poorly formed
                        data = jsondecode(a);
                    catch ME
                        fprintf(2,'Error thrown in JSON decode\n');
                        fprintf(2,'JSON msg poorly formatted?\n');
                        fprintf(2,'%s\n', ME.message);
                        return
                    end
            %         data = jsondecode(a);
                    % Gather data to update axes
                    serialInBuffer= circshift(serialInBuffer,1);
                    serialInBuffer(1) = data.temperatureInside;
                    timeBuffer = circshift(timeBuffer,1);
                    timeBuffer(1) = data.time/1000;
                    % Update Plots
                    app.axesTemp.XLim = [timeBuffer(end), timeBuffer(1)];

                    %plot(app.axesTemp, timeBuffer, serialInBuffer)
                    app.hTempPlot.XData = timeBuffer;
                    app.hTempPlot.YData = serialInBuffer;
                    drawnow()

                    % Update textbox values
                    %set(app.editSerialIn, 'string', num2str(data.temperatureInside));
                    app.editSerialIn.Value = num2str(data.temperatureInside);
                    % Save data to disk
                    if ~isnan(dataFile)
                        save_data(app, serialInBuffer(1),...
                                  timeBuffer(1));
                    end
%                 end
        end
        
        function save_data(app, tIn, time)
            % Save data to the open csv file. This file is opened in
            % PBfile_Callback function.
            global dataFile;
            dlmwrite(dataFile,[tIn,time], '-append')
        end
        

        function setup_serial(app, comPort)

              % Initialize Serial object
              app.serialPort = serialport(comPort, 9600);
              %set(obj,'DataBits',8);
              %set(obj,'StopBits',1);
              %set(obj,'Parity','none');
              
              configureCallback(app.serialPort,"terminator", @app.instrcallback)
        end
        

        function updateArduinoVals(app)
                % update the PID and temperature setpoint values in the arduino
                serialData = jsonencode(app.arduinoVals);
                disp("Serial Data is:")
                disp(serialData)
            
                writeline(app.serialPort,serialData);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Plot_Serial_OpeningFcn(app, varargin)
            % --- Executes just before Plot_Serial is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
                % This function has no output args, see OutputFcn.
                % hObject    handle to figure
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                % varargin   command line arguments to Plot_Serial (see VARARGIN)
            
                % Reset the com ports in case the Arduino was not properly disconnected
                % before the program quit previously.
                % instrreset - this function was removed ny MatLab 2022
                clear serialObj
            
                global serialInBuffer
                serialInBuffer = zeros(100,1);
                global timeBuffer
                timeBuffer = zeros(size(serialInBuffer));
            
                global dataFile
                dataFile = NaN;
            
            
                % Choose default command line output for Plot_Serial
                handles.output = hObject;
            
                handles.serialInBuffer = zeros(10,1);
                handles.temperatureSetpointBuffer = zeros(size(handles.serialInBuffer));
            
                % Update handles structure
                guidata(hObject, handles);
            
                % UIWAIT makes Plot_Serial wait for user response (see UIRESUME)
                % uiwait(handles.figure1);
            
                % Plot for the inside, outside and setpoint temperatures
                %x_data = 1:1:length(serialInBuffer);
                x_data = timeBuffer;
                app.hTempPlot = plot(handles.axesTemp, x_data, serialInBuffer);
                
                set(handles.axesTemp, 'YLim', [0,25]);
                title('Temperature');
                handles.axesTemp.YLabel.String = 'Degrees Celsius';
                handles.axesTemp.XLabel.String = 'Time (s)';
                legend('Temp Inside', 'Location', 'southwest');
            
                guidata(hObject, handles);
            
            
                %comPort = choose_usb_dialog(app);
                %serialObj = setup_serial(app, comPort, hObject, handles);
                %handles.comPort = comPort;
                %handles.serialObj = serialObj;
                guidata(hObject, handles);
        end

        % Button pushed function: pushbuttonFile
        function pushbuttonFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Create file and open fid for saving data to disk.
            %
            % hObject    handle to PBfile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global dataFile;
            [filename,pathname] = uiputfile('*.csv', 'Select file to save data');
            handles.filename = filename;
            handles.pathname = pathname;
            dataFile = fullfile(pathname, filename);
            fid = fopen(dataFile,'w');
            fprintf(fid,'InsideTempC,TimeS\n');
            fclose(fid);
            guidata(hObject, handles);
        end

        % Value changed function: editSerialIn
        function editSerialIn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editInTemp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editInTemp as text
            %        str2double(get(hObject,'String')) returns contents of editInTemp as a double
        end

        % Button pushed function: pushbuttonStop
        function pushbuttonStop_Callback(app, event)
            % --- Executes on button press in pushbuttonStop.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % hObject    handle to pushbuttonStop (see GCBO)
                % eventdata  reserved - to be defined in a future version of MATLAB
                % handles    structure with handles and user data (see GUIDATA)
                %fclose(handles.serialObj);
                clear app.serialPort
                close(handles.figure1);
        end

        % Drop down opening function: ChooseUSBDropDown
        function ChooseUSBDropDownOpening(app, event)
            % Get the list of available ports connected to the computer
                ports = cellstr(serialportlist);
            
                % Prepend 'none' selection to ports cell array to allow for running
                %  the UI without a connected Arduino, FUTURE FEATURE
                ports = [{'none'},ports];

                app.ChooseUSBDropDown.Items = ports;
                
        end

        % Value changed function: ChooseUSBDropDown
        function ChooseUSBDropDownValueChanged(app, event)
            comPort = app.ChooseUSBDropDown.Value;
            %comPort = choose_usb_dialog(app);

            %serialObj = setup_serial(app, comPort, hObject, handles);
            setup_serial(app, comPort);

% % %             handles.comPort = comPort;
% % %             handles.serialObj = serialObj;
% % %             guidata(hObject, handles);
        end

        % Value changed function: editSerialOut
        function editSerialOutValueChanged(app, event)
            app.arduinoVals.pVal = app.editSerialOut.Value;
            app.updateArduinoVals()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Plot_Serial';
            app.figure1.Tag = 'figure1';
            app.axesTemp = uiaxes(app.figure1);
            app.axesTemp.Tag = 'axesTemp';
            app.ControlsPanel = uipanel(app.figure1);
            app.ControlsPanel.Title = 'Controls';
            app.pushbuttonFile = uibutton(app.ControlsPanel, 'push');
            app.pushbuttonFile.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFile_Callback, true);
            app.pushbuttonFile.Tag = 'PBfile';
            app.pushbuttonFile.Text = 'Write to File';
            app.PressButtontobeginLabel = uilabel(app.ControlsPanel);
            app.PressButtontobeginLabel.Text = 'Press Button to begin ';
            app.writingtoafileLabel = uilabel(app.ControlsPanel);
            app.writingtoafileLabel.Text = 'writing to a file. ';
            app.pushbuttonStop = uibutton(app.ControlsPanel, 'push');
            app.pushbuttonStop.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStop_Callback, true);
            app.pushbuttonStop.Tag = 'pushbuttonStop';
            app.pushbuttonStop.Text = 'Stop';
            app.ChooseUSBDropDownLabel = uilabel(app.ControlsPanel);
            app.ChooseUSBDropDownLabel.Text = 'Choose USB';
            app.ChooseUSBDropDown = uidropdown(app.ControlsPanel);
            app.ChooseUSBDropDown.Items = {'click me'};
            app.ChooseUSBDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseUSBDropDownOpening, true);
            app.ChooseUSBDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseUSBDropDownValueChanged, true);
            app.ChooseUSBDropDown.Value = 'click me';
            app.SerialCommunicationsDataPanel = uipanel(app.figure1);
            app.SerialCommunicationsDataPanel.Title = 'Serial Communications Data';
            app.editSerialOut = uieditfield(app.SerialCommunicationsDataPanel, 'text');
            app.editSerialOut.ValueChangedFcn = createCallbackFcn(app, @editSerialOutValueChanged, true);
            app.editSerialOut.Tag = 'editSerialOut';
            app.editSerialOut.Value = '20';
            app.text3 = uilabel(app.SerialCommunicationsDataPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Serial Out';
            app.editSerialIn = uieditfield(app.SerialCommunicationsDataPanel, 'text');
            app.editSerialIn.ValueChangedFcn = createCallbackFcn(app, @editSerialIn_Callback, true);
            app.editSerialIn.Tag = 'editSerialIn';
            app.text6 = uilabel(app.SerialCommunicationsDataPanel);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Serial In';
            app.Setto123toturnLEDonLabel = uilabel(app.SerialCommunicationsDataPanel);
            app.Setto123toturnLEDonLabel.Text = 'Set to 1.23 to turn LED on';
            app.PlotSerialInDataLabel = uilabel(app.figure1);
            app.PlotSerialInDataLabel.Text = 'Plot Serial In Data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_Serial_App_nopopup(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Plot_Serial_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end